classdef rotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CELL_ID      matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Panel        matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        ResetButton  matlab.ui.control.Button
        DrawButton   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        rotation_stack = struct( ...
            'roi', {[]}, ...
            'gui', {{}}, ...
            'listeners', {{}}, ...
            'cache', {0});

        VolumeDropDown = struct('Value', {'Colormap'});
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rotation_stack.cache = struct('Colormap', {{}}, 'Video', {{}});
            
            test_img = load('test_file.mat');
            test_data = test_img.data;
            test_view = squeeze(test_data(:, :, 15, 1:3)*1100);
            image(app.UIAxes, test_view);
            daspect(app.UIAxes, [1 1 1]);
            app.UIAxes.XLim(2) = size(test_view, 2);
            app.UIAxes.YLim(2) = size(test_view, 1);
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            roi = drawrectangle(app.UIAxes,'Color','black','StripeColor','m');
            Program.rotation_gui.draw(app, roi);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CELL_ID = uifigure('Visible', 'off');
            app.CELL_ID.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.CELL_ID);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.DrawButton = uibutton(app.GridLayout2, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.CELL_ID.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CELL_ID)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CELL_ID)
        end
    end
end